classdef SPtoolboxGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SPtoolbox10UIFigure         matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        SettingTab                  matlab.ui.container.Tab
        ParametersPanel             matlab.ui.container.Panel
        Panel_2                     matlab.ui.container.Panel
        SampleDropDown              matlab.ui.control.DropDown
        SampleDropDownLabel         matlab.ui.control.Label
        TimesTextArea               matlab.ui.control.TextArea
        TimesTextAreaLabel          matlab.ui.control.Label
        Panel                       matlab.ui.container.Panel
        DetrendCheckBox             matlab.ui.control.CheckBox
        CorrectionCheckBox          matlab.ui.control.CheckBox
        NormalizationCheckBox       matlab.ui.control.CheckBox
        DerivativeCheckBox          matlab.ui.control.CheckBox
        SmoothingCheckBox           matlab.ui.control.CheckBox
        RunButton                   matlab.ui.control.Button
        SepectraDataPanel           matlab.ui.container.Panel
        LoadDataButton              matlab.ui.control.Button
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        ProcessedResultsTab         matlab.ui.container.Tab
        ShowOutButton               matlab.ui.control.Button
        ShowInButton                matlab.ui.control.Button
        SpectraProcessed_10         matlab.ui.control.UIAxes
        SpectraProcessed_9          matlab.ui.control.UIAxes
        SpectraProcessed_8          matlab.ui.control.UIAxes
        SpectraProcessed_7          matlab.ui.control.UIAxes
        SpectraProcessed_6          matlab.ui.control.UIAxes
        SpectraProcessed_5          matlab.ui.control.UIAxes
        SpectraProcessed_4          matlab.ui.control.UIAxes
        SpectraProcessed_3          matlab.ui.control.UIAxes
        SpectraProcessed_2          matlab.ui.control.UIAxes
        SpectraProcessed_1          matlab.ui.control.UIAxes
        PerformanceTab              matlab.ui.container.Tab
        ShowOutButton_2             matlab.ui.control.Button
        ShowInButton_2              matlab.ui.control.Button
        UIAxes3_4                   matlab.ui.control.UIAxes
        UIAxes3_3                   matlab.ui.control.UIAxes
        UIAxes3_2                   matlab.ui.control.UIAxes
        UIAxes3                     matlab.ui.control.UIAxes
        MethodsTab                  matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        Ref_PreTab                  matlab.ui.container.Tab
        ShowButton_RefVSPre1_3      matlab.ui.control.Button
        ModelIndexEditField_3       matlab.ui.control.NumericEditField
        ModelIndexEditField_3Label  matlab.ui.control.Label
        ShowButton_RefVSPre1_2      matlab.ui.control.Button
        ModelIndexEditField_2       matlab.ui.control.NumericEditField
        ModelIndexEditField_2Label  matlab.ui.control.Label
        ModelIndexEditField         matlab.ui.control.NumericEditField
        ModelIndexEditFieldLabel    matlab.ui.control.Label
        ShowButton_RefVSPre1        matlab.ui.control.Button
        UIAxes_RefVSPre1_3          matlab.ui.control.UIAxes
        UIAxes_RefVSPre1_2          matlab.ui.control.UIAxes
        UIAxes_RefVSPre1            matlab.ui.control.UIAxes
        AutomaticSpectraProcessingToolboxLabel  matlab.ui.control.Label
    end

    
    properties (Access = public)
        Data
        SP
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
               app.Data = load(fullfile(path,file));
               plot(app.UIAxes, app.Data.Data.spectra');
               axis(app.UIAxes,'tight')
               histogram(app.UIAxes2,app.Data.Data.y)
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            para = [];
            if app.SmoothingCheckBox.Value == 1
                para= [para, "sm"];
            end
            if app.DerivativeCheckBox.Value == 1
                para= [para , "df"];
            end
            if app.NormalizationCheckBox.Value == 1
                para= [para, "nm"];
            end
            if app.CorrectionCheckBox.Value == 1
                para = [para, "ct"];
            end
            if app.DetrendCheckBox.Value == 1
                para = [para, "de"] ;
            end
            
            if isempty(para)
                errordlg('Select at least one option','Error');
                return
            end
            
            if isempty(app.Data)  
                errordlg('Spectra data needed!','Error');
                return
            end
            
            app.SP = createSP(app.Data.Data, para, str2double(app.TimesTextArea.Value)) ;
            app.SP.autorun()
            set(app.UITable, 'data', app.SP.MethodCombination, 'RowName', 'numbered');  
            app.TabGroup.SelectedTab = app.ProcessedResultsTab;                

        end

        % Button pushed function: ShowInButton
        function ShowInButtonPushed(app, event)
            [~, ~] = size(app.SP.OptimalResult);
            
            spectra = app.SP.OptimalResult{1, 1}.DataProcessed.spectra;
            name = app.SP.OptimalResult{1, 1}.MethodName;
            plot(app.SpectraProcessed_1, spectra')
            title(app.SpectraProcessed_1, table2str(name))
            
            spectra = app.SP.OptimalResult{2, 1}.DataProcessed.spectra;
            name = app.SP.OptimalResult{2, 1}.MethodName;
            plot(app.SpectraProcessed_2, spectra')
            title(app.SpectraProcessed_2, table2str(name))
            
            spectra = app.SP.OptimalResult{3, 1}.DataProcessed.spectra;
            name = app.SP.OptimalResult{3, 1}.MethodName;
            plot(app.SpectraProcessed_3, spectra')
            title(app.SpectraProcessed_3, table2str(name))
            
            spectra = app.SP.OptimalResult{4, 1}.DataProcessed.spectra;
            name = app.SP.OptimalResult{4, 1}.MethodName;
            plot(app.SpectraProcessed_4, spectra')
            title(app.SpectraProcessed_4, table2str(name))
            
            spectra = app.SP.OptimalResult{5, 1}.DataProcessed.spectra;
            name = app.SP.OptimalResult{5, 1}.MethodName;
            plot(app.SpectraProcessed_5, spectra')
            title(app.SpectraProcessed_5, table2str(name))
            
            spectra = app.SP.OptimalResult{6, 1}.DataProcessed.spectra;
            name = app.SP.OptimalResult{6, 1}.MethodName;
            plot(app.SpectraProcessed_6, spectra')
            title(app.SpectraProcessed_6, table2str(name))
            
            spectra = app.SP.OptimalResult{7, 1}.DataProcessed.spectra;
            name = app.SP.OptimalResult{7, 1}.MethodName;
            plot(app.SpectraProcessed_7, spectra')
            title(app.SpectraProcessed_7, table2str(name))
            
            spectra = app.SP.OptimalResult{8, 1}.DataProcessed.spectra;
            name = app.SP.OptimalResult{8, 1}.MethodName;
            plot(app.SpectraProcessed_8, spectra')
            title(app.SpectraProcessed_8, table2str(name))
            
            spectra = app.SP.OptimalResult{9, 1}.DataProcessed.spectra;
            name = app.SP.OptimalResult{9, 1}.MethodName;
            plot(app.SpectraProcessed_9, spectra')
            title(app.SpectraProcessed_9, table2str(name))
            
            spectra = app.SP.OptimalResult{10, 1}.DataProcessed.spectra;
            name = app.SP.OptimalResult{10, 1}.MethodName;
            plot(app.SpectraProcessed_10, spectra')
            title(app.SpectraProcessed_10, table2str(name))
            
        end

        % Button pushed function: ShowInButton_2
        function ShowInButton_2Pushed(app, event)
            n = size(app.SP.PerformanceSta.RMSECV,2);
            tick = 1:n;
            tickcell = num2cell(tick);
            if (n >= 30)
                tick = floor(linspace(1,n,10));
                tickcell = num2cell(tick);
            end
            
            boxplot(app.UIAxes3, app.SP.PerformanceSta.RMSECV)       
            xticks(app.UIAxes3, tick);
            xticklabels(app.UIAxes3,[]);
            
            boxplot(app.UIAxes3_2, app.SP.PerformanceSta.RMSEC)       
            xticks(app.UIAxes3_2, tick);
            xticklabels(app.UIAxes3_2,[]);
            
            boxplot(app.UIAxes3_3, app.SP.PerformanceSta.RMSEV)       
            xticks(app.UIAxes3_3, tick);
            xticklabels(app.UIAxes3_3,[]);
            
            boxplot(app.UIAxes3_4, app.SP.PerformanceSta.LV)       
            xticks(app.UIAxes3_4, tick);
            xticklabels(app.UIAxes3_4,tickcell);
            
        end

        % Button pushed function: ShowOutButton
        function ShowOutButtonPushed(app, event)
            app.SP.plotProcessedSpec(app.Data.wavelength);
        end

        % Button pushed function: ShowOutButton_2
        function ShowOutButton_2Pushed(app, event)
            app.SP.plotAllPerformance(2);
        end

        % Button pushed function: ShowButton_RefVSPre1
        function ShowButton_RefVSPre1Pushed(app, event)
            if isempty(app.SP)
                errordlg('Please Run the main procedure!','Error');
                return
            end
            cla(app.UIAxes_RefVSPre1)
            
            ind = app.ModelIndexEditField.Value;
            preCal = app.SP.OptimalResult{ind, 1}.PerformanceCal.yfit;
            refCal = app.SP.OptimalResult{ind, 1}.ycal;
            preVal = app.SP.OptimalResult{ind, 1}.PerformanceVal.yfit;
            refVal = app.SP.OptimalResult{ind, 1}.yval;
            name = app.SP.OptimalResult{ind, 1}.MethodName;  
            
            
            hold(app.UIAxes_RefVSPre1,"on")
            s1 = scatter(app.UIAxes_RefVSPre1, refCal, preCal);
            s2 = scatter(app.UIAxes_RefVSPre1, refVal, preVal);
            box(app.UIAxes_RefVSPre1,"on")
            s2.Marker = '*';
            xl = app.UIAxes_RefVSPre1.XLim;
            yl = app.UIAxes_RefVSPre1.YLim;
            xx = [xl(1), xl(1)];
            yy = [yl(2), yl(2)];
            line(app.UIAxes_RefVSPre1, xl, xl)
            
            str1 = strcat("R_c^2: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceCal.R2, 3));
            str2 = strcat("R_v^2: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceVal.R2, 3));
            str3 = strcat("RMSEC: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceCal.RMSE, 3));
            str4 = strcat("RMSEV: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceVal.RMSE, 3));
            str5 = strcat("LV: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceCal.LV));
            str = [str1, str2, str3, str4, str5];
            text(app.UIAxes_RefVSPre1, 'string', str, 'Units', 'normalized', 'Position', [0.5, 0.2], 'Fontsize',7)
            s = table2str(name);
            if s == ""
                s = "Raw";
            end
            title(app.UIAxes_RefVSPre1, s)
            hold(app.UIAxes_RefVSPre1,"off")
            
        end

        % Button pushed function: ShowButton_RefVSPre1_2
        function ShowButton_RefVSPre1_2Pushed(app, event)
            if isempty(app.SP)
                errordlg('Please Run the main procedure!','Error');
                return
            end
            
            cla(app.UIAxes_RefVSPre1_2)
            
            ind = app.ModelIndexEditField_2.Value;
            preCal = app.SP.OptimalResult{ind, 1}.PerformanceCal.yfit;
            refCal = app.SP.OptimalResult{ind, 1}.ycal;
            preVal = app.SP.OptimalResult{ind, 1}.PerformanceVal.yfit;
            refVal = app.SP.OptimalResult{ind, 1}.yval;
            name = app.SP.OptimalResult{ind, 1}.MethodName;  
            
            
            hold(app.UIAxes_RefVSPre1_2,"on")
            s1 = scatter(app.UIAxes_RefVSPre1_2, refCal, preCal);
            s2 = scatter(app.UIAxes_RefVSPre1_2, refVal, preVal);
            box(app.UIAxes_RefVSPre1_2,"on")
            s2.Marker = '*';
            xl = app.UIAxes_RefVSPre1_2.XLim;
            yl = app.UIAxes_RefVSPre1_2.YLim;
            xx = [xl(1), xl(1)];
            yy = [yl(2), yl(2)];
            line(app.UIAxes_RefVSPre1_2, xl, xl)
            
            str1 = strcat("R_c^2: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceCal.R2, 3));
            str2 = strcat("R_v^2: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceVal.R2, 3));
            str3 = strcat("RMSEC: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceCal.RMSE, 3));
            str4 = strcat("RMSEV: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceVal.RMSE, 3));
            str5 = strcat("LV: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceCal.LV));
            str = [str1, str2, str3, str4, str5];
            text(app.UIAxes_RefVSPre1_2, 'string', str, 'Units', 'normalized', 'Position', [0.5, 0.2], 'Fontsize',7)
            s = table2str(name);
            if s == ""
                s = "Raw";
            end
            title(app.UIAxes_RefVSPre1_2, s)
            hold(app.UIAxes_RefVSPre1_2,"off")
        end

        % Button pushed function: ShowButton_RefVSPre1_3
        function ShowButton_RefVSPre1_3Pushed(app, event)
            if isempty(app.SP)
                errordlg('Please Run the main procedure!','Error');
                return
            end
            
            cla(app.UIAxes_RefVSPre1_3)
            
            ind = app.ModelIndexEditField_3.Value;
            preCal = app.SP.OptimalResult{ind, 1}.PerformanceCal.yfit;
            refCal = app.SP.OptimalResult{ind, 1}.ycal;
            preVal = app.SP.OptimalResult{ind, 1}.PerformanceVal.yfit;
            refVal = app.SP.OptimalResult{ind, 1}.yval;
            name = app.SP.OptimalResult{ind, 1}.MethodName;  
            
            
            hold(app.UIAxes_RefVSPre1_3,"on")
            s1 = scatter(app.UIAxes_RefVSPre1_3, refCal, preCal);
            s2 = scatter(app.UIAxes_RefVSPre1_3, refVal, preVal);
            box(app.UIAxes_RefVSPre1_3,"on")
            s2.Marker = '*';
            xl = app.UIAxes_RefVSPre1_3.XLim;
            yl = app.UIAxes_RefVSPre1_3.YLim;
            xx = [xl(1), xl(1)];
            yy = [yl(2), yl(2)];
            line(app.UIAxes_RefVSPre1_3, xl, xl)
            
            str1 = strcat("R_c^2: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceCal.R2, 3));
            str2 = strcat("R_v^2: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceVal.R2, 3));
            str3 = strcat("RMSEC: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceCal.RMSE, 3));
            str4 = strcat("RMSEV: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceVal.RMSE, 3));
            str5 = strcat("LV: ", num2str(app.SP.OptimalResult{ind, 1}.PerformanceCal.LV));
            str = [str1, str2, str3, str4, str5];
            text(app.UIAxes_RefVSPre1_3, 'string', str, 'Units', 'normalized', 'Position', [0.5, 0.2], 'Fontsize',7)
            s = table2str(name);
            if s == ""
                s = "Raw";
            end
            title(app.UIAxes_RefVSPre1_3, s)
            hold(app.UIAxes_RefVSPre1_3,"off")
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SPtoolbox10UIFigure = uifigure('Visible', 'off');
            app.SPtoolbox10UIFigure.Name = 'SPtoolbox 1.0';
            app.AutomaticSpectraProcessingToolboxLabel = uilabel(app.SPtoolbox10UIFigure);
            app.AutomaticSpectraProcessingToolboxLabel.Text = 'Automatic Spectra Processing Toolbox';
            app.TabGroup = uitabgroup(app.SPtoolbox10UIFigure);
            app.SettingTab = uitab(app.TabGroup);
            app.SettingTab.Title = 'Setting';
            app.SepectraDataPanel = uipanel(app.SettingTab);
            app.SepectraDataPanel.Title = 'Sepectra Data';
            app.UIAxes = uiaxes(app.SepectraDataPanel);
            title(app.UIAxes, 'Spectra')
            xlabel(app.UIAxes, 'Wavelength')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes2 = uiaxes(app.SepectraDataPanel);
            title(app.UIAxes2, 'Concentration')
            xlabel(app.UIAxes2, 'Y')
            ylabel(app.UIAxes2, {''; ''})
            app.LoadDataButton = uibutton(app.SepectraDataPanel, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.ParametersPanel = uipanel(app.SettingTab);
            app.ParametersPanel.Title = 'Parameters';
            app.RunButton = uibutton(app.ParametersPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.Panel = uipanel(app.ParametersPanel);
            app.SmoothingCheckBox = uicheckbox(app.Panel);
            app.SmoothingCheckBox.Text = 'Smoothing';
            app.DerivativeCheckBox = uicheckbox(app.Panel);
            app.DerivativeCheckBox.Text = 'Derivative';
            app.DerivativeCheckBox.Value = true;
            app.NormalizationCheckBox = uicheckbox(app.Panel);
            app.NormalizationCheckBox.Text = 'Normalization';
            app.CorrectionCheckBox = uicheckbox(app.Panel);
            app.CorrectionCheckBox.Text = 'Correction';
            app.DetrendCheckBox = uicheckbox(app.Panel);
            app.DetrendCheckBox.Text = 'Detrend';
            app.Panel_2 = uipanel(app.ParametersPanel);
            app.TimesTextAreaLabel = uilabel(app.Panel_2);
            app.TimesTextAreaLabel.Text = {'Times'; ''};
            app.TimesTextArea = uitextarea(app.Panel_2);
            app.TimesTextArea.Value = {'30'};
            app.SampleDropDownLabel = uilabel(app.Panel_2);
            app.SampleDropDownLabel.Text = 'Sample';
            app.SampleDropDown = uidropdown(app.Panel_2);
            app.SampleDropDown.Items = {'spxy', 'ks', 'random'};
            app.SampleDropDown.Value = 'spxy';
            app.ProcessedResultsTab = uitab(app.TabGroup);
            app.ProcessedResultsTab.Title = 'Processed Results';
            app.SpectraProcessed_1 = uiaxes(app.ProcessedResultsTab);
            xlabel(app.SpectraProcessed_1, '(1)')
            app.SpectraProcessed_2 = uiaxes(app.ProcessedResultsTab);
            xlabel(app.SpectraProcessed_2, '(2)')
            app.SpectraProcessed_3 = uiaxes(app.ProcessedResultsTab);
            xlabel(app.SpectraProcessed_3, '(3)')
            app.SpectraProcessed_4 = uiaxes(app.ProcessedResultsTab);
            xlabel(app.SpectraProcessed_4, '(4)')
            app.SpectraProcessed_5 = uiaxes(app.ProcessedResultsTab);
            xlabel(app.SpectraProcessed_5, '(5)')
            app.SpectraProcessed_6 = uiaxes(app.ProcessedResultsTab);
            xlabel(app.SpectraProcessed_6, '(6)')
            app.SpectraProcessed_7 = uiaxes(app.ProcessedResultsTab);
            xlabel(app.SpectraProcessed_7, '(7)')
            app.SpectraProcessed_8 = uiaxes(app.ProcessedResultsTab);
            xlabel(app.SpectraProcessed_8, '(8)')
            app.SpectraProcessed_9 = uiaxes(app.ProcessedResultsTab);
            xlabel(app.SpectraProcessed_9, '(9)')
            app.SpectraProcessed_10 = uiaxes(app.ProcessedResultsTab);
            xlabel(app.SpectraProcessed_10, '(10)')
            app.ShowInButton = uibutton(app.ProcessedResultsTab, 'push');
            app.ShowInButton.ButtonPushedFcn = createCallbackFcn(app, @ShowInButtonPushed, true);
            app.ShowInButton.Text = 'Show In';
            app.ShowOutButton = uibutton(app.ProcessedResultsTab, 'push');
            app.ShowOutButton.ButtonPushedFcn = createCallbackFcn(app, @ShowOutButtonPushed, true);
            app.ShowOutButton.Text = 'Show Out';
            app.PerformanceTab = uitab(app.TabGroup);
            app.PerformanceTab.Title = 'Performance';
            app.UIAxes3 = uiaxes(app.PerformanceTab);
            xlabel(app.UIAxes3, {''; ''})
            ylabel(app.UIAxes3, 'RMSECV')
            app.UIAxes3.XLim = [0 1];
            app.UIAxes3_2 = uiaxes(app.PerformanceTab);
            ylabel(app.UIAxes3_2, 'RMSEC')
            app.UIAxes3_2.XLim = [0 1];
            app.UIAxes3_3 = uiaxes(app.PerformanceTab);
            ylabel(app.UIAxes3_3, 'RMSEV')
            app.UIAxes3_3.XLim = [0 1];
            app.UIAxes3_4 = uiaxes(app.PerformanceTab);
            xlabel(app.UIAxes3_4, 'Group')
            ylabel(app.UIAxes3_4, 'LV')
            app.UIAxes3_4.XLim = [0 1];
            app.ShowInButton_2 = uibutton(app.PerformanceTab, 'push');
            app.ShowInButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowInButton_2Pushed, true);
            app.ShowInButton_2.Text = 'Show In';
            app.ShowOutButton_2 = uibutton(app.PerformanceTab, 'push');
            app.ShowOutButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowOutButton_2Pushed, true);
            app.ShowOutButton_2.Text = 'Show Out';
            app.MethodsTab = uitab(app.TabGroup);
            app.MethodsTab.Title = 'Methods';
            app.UITable = uitable(app.MethodsTab);
            app.UITable.ColumnName = {'Method1'; 'Method2'; 'Method3'; 'Method4'; 'Method5'};
            app.UITable.RowName = {};
            app.Ref_PreTab = uitab(app.TabGroup);
            app.Ref_PreTab.Title = 'Ref_Pre';
            app.UIAxes_RefVSPre1 = uiaxes(app.Ref_PreTab);
            title(app.UIAxes_RefVSPre1, 'Title')
            xlabel(app.UIAxes_RefVSPre1, 'X')
            ylabel(app.UIAxes_RefVSPre1, 'Y')
            zlabel(app.UIAxes_RefVSPre1, 'Z')
            app.UIAxes_RefVSPre1_2 = uiaxes(app.Ref_PreTab);
            title(app.UIAxes_RefVSPre1_2, 'Title')
            xlabel(app.UIAxes_RefVSPre1_2, 'X')
            ylabel(app.UIAxes_RefVSPre1_2, 'Y')
            zlabel(app.UIAxes_RefVSPre1_2, 'Z')
            app.UIAxes_RefVSPre1_3 = uiaxes(app.Ref_PreTab);
            title(app.UIAxes_RefVSPre1_3, 'Title')
            xlabel(app.UIAxes_RefVSPre1_3, 'X')
            ylabel(app.UIAxes_RefVSPre1_3, 'Y')
            zlabel(app.UIAxes_RefVSPre1_3, 'Z')
            app.ShowButton_RefVSPre1 = uibutton(app.Ref_PreTab, 'push');
            app.ShowButton_RefVSPre1.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_RefVSPre1Pushed, true);
            app.ShowButton_RefVSPre1.Text = 'Show';
            app.ModelIndexEditFieldLabel = uilabel(app.Ref_PreTab);
            app.ModelIndexEditFieldLabel.Text = 'Model Index';
            app.ModelIndexEditField = uieditfield(app.Ref_PreTab, 'numeric');
            app.ModelIndexEditField.Value = 1;
            app.ModelIndexEditField_2Label = uilabel(app.Ref_PreTab);
            app.ModelIndexEditField_2Label.Text = 'Model Index';
            app.ModelIndexEditField_2 = uieditfield(app.Ref_PreTab, 'numeric');
            app.ModelIndexEditField_2.Value = 1;
            app.ShowButton_RefVSPre1_2 = uibutton(app.Ref_PreTab, 'push');
            app.ShowButton_RefVSPre1_2.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_RefVSPre1_2Pushed, true);
            app.ShowButton_RefVSPre1_2.Text = 'Show';
            app.ModelIndexEditField_3Label = uilabel(app.Ref_PreTab);
            app.ModelIndexEditField_3Label.Text = 'Model Index';
            app.ModelIndexEditField_3 = uieditfield(app.Ref_PreTab, 'numeric');
            app.ModelIndexEditField_3.Value = 1;
            app.ShowButton_RefVSPre1_3 = uibutton(app.Ref_PreTab, 'push');
            app.ShowButton_RefVSPre1_3.ButtonPushedFcn = createCallbackFcn(app, @ShowButton_RefVSPre1_3Pushed, true);
            app.ShowButton_RefVSPre1_3.Text = 'Show';
            % Show the figure after all components are created
            app.SPtoolbox10UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SPtoolboxGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SPtoolbox10UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SPtoolbox10UIFigure)
        end
    end
end